classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        StopButton                     matlab.ui.control.StateButton
        StartButton                    matlab.ui.control.StateButton
        YellowLightTimeEditField       matlab.ui.control.NumericEditField
        YellowLightTimeEditFieldLabel  matlab.ui.control.Label
        RedLightTimeEditField          matlab.ui.control.NumericEditField
        RedLightTimeEditFieldLabel     matlab.ui.control.Label
        GreenLightTimeEditField        matlab.ui.control.NumericEditField
        GreenLightTimeEditFieldLabel   matlab.ui.control.Label
    end

    
    properties (Access = private)
        simulationTimer; % Timer object for the simulation
    end
    
    
    methods (Access = private)
        function UpdateRedLightTime(app)
            
    app.RedLightTimeEditField.Value = app.GreenLightTimeEditField.Value + app.YellowLightTimeEditField.Value;

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: GreenLightTimeEditField
        function GreenLightTime(app, event)
            value1 = app.GreenLightTimeEditField.Value;
            UpdateRedLightTime(app) ;
        end

        % Value changed function: YellowLightTimeEditField
        function YellowLightTime(app, event)
            value2 = app.YellowLightTimeEditField.Value;
            UpdateRedLightTime(app) ;
        end

        % Value changed function: RedLightTimeEditField
        function RedLightTime(app, event)
            value3 = app.RedLightTimeEditField.Value;
            
        end

        % Value changed function: StartButton
        function StartButtonPushed(app, event)
        global Running;
    
        % Initialize the Running state
        Running = true;

        x = app.GreenLightTimeEditField.Value;
        y = app.YellowLightTimeEditField.Value;
        z = app.RedLightTimeEditField.Value;

        % Placeholder for the external function
        visualize_traffic_lights_with_pedestrian(x, y, z);  


        end

        % Value changed function: StopButton
        function StopButtonPushed(app, event)
        global Running;
    
        % Set the Running state to false to stop the simulation
        Running = false;
    
        % Close the GUI window
        delete(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GreenLightTimeEditFieldLabel = uilabel(app.UIFigure);
            app.GreenLightTimeEditFieldLabel.Text = 'Green Light Time';
            app.GreenLightTimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.GreenLightTimeEditField.ValueChangedFcn = createCallbackFcn(app, @GreenLightTime, true);
            app.GreenLightTimeEditField.Value = 5;
            app.RedLightTimeEditFieldLabel = uilabel(app.UIFigure);
            app.RedLightTimeEditFieldLabel.Text = 'Red Light Time';
            app.RedLightTimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.RedLightTimeEditField.ValueChangedFcn = createCallbackFcn(app, @RedLightTime, true);
            app.YellowLightTimeEditFieldLabel = uilabel(app.UIFigure);
            app.YellowLightTimeEditFieldLabel.Text = 'Yellow Light Time';
            app.YellowLightTimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.YellowLightTimeEditField.ValueChangedFcn = createCallbackFcn(app, @YellowLightTime, true);
            app.YellowLightTimeEditField.Value = 2;
            app.StartButton = uibutton(app.UIFigure, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'StartButton';
            app.StopButton = uibutton(app.UIFigure, 'state');
            app.StopButton.ValueChangedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'StopButton';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end